classdef EEGsonic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSonicUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        QuitEEGsonicMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralSettingsTab              matlab.ui.container.Tab
        Feature1SettingsTab             matlab.ui.container.Tab
        Feature2SettingsTab             matlab.ui.container.Tab
        Feature3SettingsTab             matlab.ui.container.Tab
        Feature4SettingsTab             matlab.ui.container.Tab
        SonificationSettingsTab         matlab.ui.container.Tab
        EEGSonicEGIeditionPanel         matlab.ui.container.Panel
        Feature1CheckBox                matlab.ui.control.CheckBox
        Feature2CheckBox                matlab.ui.control.CheckBox
        Feature3CheckBox                matlab.ui.control.CheckBox
        Feature4CheckBox                matlab.ui.control.CheckBox
        Feature5CheckBox                matlab.ui.control.CheckBox
        SelectSavingDirectoryButton     matlab.ui.control.Button
        NosavingdirectoryselectedLabel  matlab.ui.control.Label
        SonificateCheckBox              matlab.ui.control.CheckBox
        LaunchEEGsonicButton            matlab.ui.control.Button
    end


    properties (Access = private)
        % Strings
        start_message;
        exit_message;
        
        parameters;
        information;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Settinng up String
            app.start_message = "Welcome to EEGsonic, an application by the Biosignal Interaction and Personhood Technology (BIAPT) lab at McGill";
            app.exit_message = "Thank you for using EEGsonic! If there is an issue with the code please send a message to yacine.mahdid@mail.mcgill.ca or visit https://github.com/BIAPT/EEGsonic/issues to open an issue";
            
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
            
            disp(mfilename('fullpath'));
            % Setting up Variables
            app_data = load(strcat(base_directory,'/data/app_data'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            
            % Welcoming the user
            clc;
            disp(app.start_message);
            
        end

        % Menu selected function: QuitEEGsonicMenu
        function QuitEEGsonicMenuSelected(app, event)
            disp(app.exit_message);
            app.delete();
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            disp('Saving Settings...');
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
            disp('Finished Saving!');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSonicUIFigure = uifigure;
            app.EEGSonicUIFigure.Name = 'EEG Sonic';
            app.FileMenu = uimenu(app.EEGSonicUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveSettingsMenu = uimenu(app.FileMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.QuitEEGsonicMenu = uimenu(app.FileMenu);
            app.QuitEEGsonicMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitEEGsonicMenuSelected, true);
            app.QuitEEGsonicMenu.Text = 'Quit EEGsonic';
            app.TabGroup = uitabgroup(app.EEGSonicUIFigure);
            app.GeneralSettingsTab = uitab(app.TabGroup);
            app.GeneralSettingsTab.Title = 'General Settings';
            app.Feature1SettingsTab = uitab(app.TabGroup);
            app.Feature1SettingsTab.Title = 'Feature 1 Settings';
            app.Feature2SettingsTab = uitab(app.TabGroup);
            app.Feature2SettingsTab.Title = 'Feature 2 Settings';
            app.Feature3SettingsTab = uitab(app.TabGroup);
            app.Feature3SettingsTab.Title = 'Feature 3 Settings';
            app.Feature4SettingsTab = uitab(app.TabGroup);
            app.Feature4SettingsTab.Title = 'Feature 4 Settings';
            app.SonificationSettingsTab = uitab(app.TabGroup);
            app.SonificationSettingsTab.Title = 'Sonification Settings';
            app.EEGSonicEGIeditionPanel = uipanel(app.EEGSonicUIFigure);
            app.EEGSonicEGIeditionPanel.Title = 'EEGSonic (EGI edition)';
            app.Feature1CheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.Feature1CheckBox.Text = 'Feature 1';
            app.Feature2CheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.Feature2CheckBox.Text = 'Feature 2';
            app.Feature3CheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.Feature3CheckBox.Text = 'Feature 3';
            app.Feature4CheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.Feature4CheckBox.Text = 'Feature 4';
            app.Feature5CheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.Feature5CheckBox.Text = 'Feature 5';
            app.SelectSavingDirectoryButton = uibutton(app.EEGSonicEGIeditionPanel, 'push');
            app.SelectSavingDirectoryButton.Text = 'Select Saving Directory';
            app.NosavingdirectoryselectedLabel = uilabel(app.EEGSonicEGIeditionPanel);
            app.NosavingdirectoryselectedLabel.Text = 'No saving directory selected';
            app.SonificateCheckBox = uicheckbox(app.EEGSonicEGIeditionPanel);
            app.SonificateCheckBox.Text = 'Sonificate';
            app.LaunchEEGsonicButton = uibutton(app.EEGSonicEGIeditionPanel, 'push');
            app.LaunchEEGsonicButton.Text = 'Launch EEGsonic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGsonic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSonicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSonicUIFigure)
        end
    end
end